classdef LLC_Calulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        TabGroup          matlab.ui.container.TabGroup
        Tab               matlab.ui.container.Tab
        GainCur           matlab.ui.control.UIAxes
        LnEditFieldLabel  matlab.ui.control.Label
        Ln                matlab.ui.control.NumericEditField
        QeEditFieldLabel  matlab.ui.control.Label
        Qe                matlab.ui.control.NumericEditField
        Plot              matlab.ui.control.Button
        Tab2              matlab.ui.container.Tab
    end


    properties (Access = private)
        fn % Description
        Qe_arr
    end


    methods (Access = private)

        % Button pushed function: Plot
        function PlotButtonPushed(app, event)
            % Get app user input
            Ln_a = app.Ln.Value;
            Qe_a = app.Qe.Value;
            app.Qe_arr = Qe_a.*[0.1 0.5 1 2 4];
            app.fn = linspace(0,2.5,1000);
            for k = 1:5
                Mg = 1 ./ sqrt((1+1./Ln_a-1./(Ln_a.*app.fn.^2)).^2 ...      
                         + ((app.fn-1./app.fn).*app.Qe_arr(k)).^2); % Plot Gain Curve  
                % Create gain curve plot in proper axes
                if app.Qe_arr(k)==1
                    plot(app.GainCur,app.fn,Mg,'r--','LineWidth',2);
                else
                    plot(app.GainCur,app.fn,Mg);
                end
                hold(app.GainCur,'on');
                grid(app.GainCur,'minor');
                legend(app.GainCur,'Qe = 0.1','Qe = 0.5',...
                    'Qe = 1','Qe = 2','Qe = 4');              
            end
            ylim(app.GainCur,[0,1.5]); 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab.Units = 'pixels';
            app.GainCur = uiaxes(app.Tab);
            title(app.GainCur, 'Gain Curve');
            xlabel(app.GainCur, 'fn');
            ylabel(app.GainCur, 'Mg');
            app.LnEditFieldLabel = uilabel(app.Tab);
            app.LnEditFieldLabel.Text = 'Ln';
            app.Ln = uieditfield(app.Tab, 'numeric');
            app.Ln.Limits = [1 10];
            app.Ln.Value = 5;
            app.QeEditFieldLabel = uilabel(app.Tab);
            app.QeEditFieldLabel.Text = 'Qe';
            app.Qe = uieditfield(app.Tab, 'numeric');
            app.Qe.Limits = [0.2 1.5];
            app.Qe.Value = 1;
            app.Plot = uibutton(app.Tab, 'push');
            app.Plot.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.Plot.Text = 'Plot';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab2.Units = 'pixels';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LLC_Calulator()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end